classdef Goal_UI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Title            matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        Name             matlab.ui.control.DropDown
        Import           matlab.ui.control.Button
        ListObj          matlab.ui.control.Table
        Obj2Way          matlab.ui.control.Table
        ResultText       matlab.ui.control.TextArea
        Info             matlab.ui.control.TextArea
        Label_3          matlab.ui.control.Label
        Code             matlab.ui.control.EditField
        Label_4          matlab.ui.control.Label
        Category         matlab.ui.control.EditField
        Label_5          matlab.ui.control.Label
        CompulsoryOrNot  matlab.ui.control.EditField
        Label_6          matlab.ui.control.Label
        Credit           matlab.ui.control.EditField
        Label_7          matlab.ui.control.Label
        Hours            matlab.ui.control.EditField
        Label_8          matlab.ui.control.Label
        Semester         matlab.ui.control.EditField
        Save             matlab.ui.control.Button
        Load             matlab.ui.control.Button
    end

    
    properties (Access = private)
        DsCurriculum = 'db_Curriculum2021a'; % 课程体系描述字段
        DsIndicators = 'db_Indicators2021'; % 毕业要求指标点（内涵观测点）
        Curriculum;
        Indicators;
        DefaultSavePath = '';
        AppInfo;
        CurrentCourse Course
        Transcript Transcript
        O2W % 课程目标与评测方式的关系矩阵
    end
    
    methods (Access = private)
        
        function docType = getType(app)
            switch app.Category.Value
                case('集中实践教学')
                    docType = '实习';
                otherwise
                    if ~isempty(regexp(app.CurrentCourse.Title,'实验','once'))
                        docType = '实验';
                    else
                        docType = '理论';
                    end                 
            end
        end
        
        function results = calcGA(app)
            % 获得成绩矩阵
            scores = app.Transcript.Detail{:,4:end};
            % 获得满分值
            fullValues = app.Transcript.SubPoints;
            % 获得关系矩阵
            o2w = app.O2W(:,2:end);
            % 检查变量尺寸
            if ~isequal(size(scores,2),length(fullValues),size(o2w,1))
                warning('成绩矩阵列数（%d）、分值向量（%d）和关系矩阵的行数（%d）不相等！',...
                    size(scores,2),length(fullValues),size(o2w,1));
                return
            end
            results = zeros(size(o2w,2),1);
            % 计算各评测方式的得分率
            x = mean(scores)./fullValues;
            % 计算各评测方式在课程目标达成计算中的权重
            for iObj = 1:size(o2w,2)
                idx = [o2w{:,iObj}];
                w = fullValues(idx)/sum(fullValues(idx));
                results(iObj) = sum(w.*x(idx));
            end
        end
        
        
        function [idx,prompt] = updateCourseInfo(app)
            load(strcat(app.DefaultSavePath,'app.mat'),'cs')
            idx = strcmp(app.Name.Value,string({cs.Title}));
            % 更新属性
            app.CurrentCourse = cs(idx);
            % 更新控件值
            app.Code.Value = app.CurrentCourse.Code;
            app.Category.Value = app.CurrentCourse.Category;
            app.CompulsoryOrNot.Value = "必修";
            app.Credit.Value = app.CurrentCourse.Credits;
            app.Hours.Value = app.CurrentCourse.ClassHour;
            app.Semester.Value = app.CurrentCourse.Semester;
%             courseIdx = strcmp(app.Name.Value,app.Curriculum.Name);
%             app.Code.Value = app.Curriculum.IDv2018(courseIdx);           
%             app.Category.Value = app.Curriculum.Type(courseIdx);            
%             app.CompulsoryOrNot.Value = "必修";            
%             app.Credit.Value = string(app.Curriculum.Credit(courseIdx));
%             app.Semester.Value = app.Curriculum.Semester(courseIdx);
%             docType = getType(app);
%             switch docType
%                 case('理论')
%                     app.Hours.Value = strcat(string(double(app.Curriculum.Credit(courseIdx))*16),"学时");
%                 case('实验')
%                     app.Hours.Value = strcat(string(double(app.Curriculum.Credit(courseIdx))*32),"学时");
%                 case('实习')
%                     app.Hours.Value = strcat(string(double(app.Curriculum.Credit(courseIdx))),"周");
%             end
            prompt = sprintf('获取%s课《%s》的课程信息！',app.CompulsoryOrNot.Value,app.Name.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            homePath = cd;
            idx = regexp(homePath,'\');
            app.DefaultSavePath = homePath(1:idx(end-3));
%             filePath = [app.DefaultSavePath,'database.mat'];
            filePath = [app.DefaultSavePath,'app.mat'];
            if exist(filePath,'file') == 2 
%                 app.Info.Value = sprintf('载入"%s”中的指标点和课程体系',filePath);
%                 load(filePath,app.DsCurriculum,app.DsIndicators);
%                 app.Curriculum = eval(app.DsCurriculum);
%                 app.Indicators = eval(app.DsIndicators);
                load(filePath,'cs')
                if exist('cs','var') == 1
                    items = string({cs.Title});
                end
%                 app.Name.Items = app.Curriculum.Name;
                app.Name.Items = items;
            else
                app.Info.Value = sprintf('找不到文件："%s”',filePath);
                app.Import.Enable = false;
            end
            app.Obj2Way.Enable = 'off';
            saveFilePath = [app.DefaultSavePath,'app1.mat'];
            if exist(saveFilePath,'file') ~= 2 
                app.Load.Enable = false;
            end
        end

        % Button pushed function: Import
        function ImportButtonPushed(app, event)
            [app.Transcript,prompt] = importTranscript(app.Name.Value);
            app.Info.Value = sprintf('%s',prompt);
            app.Obj2Way.Enable = 'on';
            % 课程目标
            colNum = size(app.ListObj.Data,1)+1;
            colNames = cell(1,colNum);
            colWidths = cell(1,colNum);
            colFormats = cell(1,colNum);
            colEditable = true(1,colNum);
            colNames{1} = '评测方式';
            colWidths{1} = 'auto';
            colFormats{1} = 'char';
            for iCol = 2:length(colNames)
                colNames{iCol} = sprintf('[o%d]',iCol-1);
                colWidths{iCol} = 40;
                colFormats{iCol} = 'logical';
            end
            app.Obj2Way.ColumnName = colNames;
            app.Obj2Way.ColumnWidth = colWidths;
            app.Obj2Way.ColumnFormat = colFormats;
            app.Obj2Way.ColumnEditable = colEditable;
            EWCodes = app.Transcript.Detail.Properties.VariableNames;
            EWDescriptions = app.Transcript.Detail.Properties.VariableDescriptions;
            EWCodes(1:3) = [];
            EWDescriptions(1:3) = [];
            dat2 = cell(length(EWCodes),colNum);
            for iRow = 1:length(EWCodes)
                dat2{iRow,1} = [EWCodes{iRow},'：',EWDescriptions{iRow}];
                dat2(iRow,2:end) = {false};
            end
            app.Obj2Way.Data = dat2;
            app.O2W = app.Obj2Way.Data; % 关系矩阵O2W初始化
        end

        % Value changed function: Name
        function NameValueChanged(app, event)
            [~,prompt] = updateCourseInfo(app);
            app.Info.Value = prompt;
            % 显示课程目标
            objs = app.CurrentCourse.Objectives;
%             idx = logical(app.Curriculum.ReqMatrix(iCourse,:));
%             indicators = app.Indicators{idx,:};
%             dat1 = cell(size(indicators,1),3);
            dat1 = cell(length(objs),3);
            for i = 1:length(objs)
                dat1{i,1} = sprintf('[o%d]',i);
                dat1{i,2} = sprintf('%s',objs{i,1});
%             for i = 1:size(indicators,1)
%                 dat1{i,1} = sprintf('%s',indicators{i,1});
%                 dat1{i,2} = sprintf('%s',indicators{i,2});
            end
            app.ListObj.Data = dat1;
            % 将课程信息存于app属性CurrentCourse
%             app.CurrentCourse = Course(app.Name.Value);
%             app.CurrentCourse.Code = app.Code.Value;
%             app.CurrentCourse.Category = app.Category.Value;
%             app.CurrentCourse.CompulsoryOrNot = true;
%             app.CurrentCourse.Credits = app.Credit.Value;
%             app.CurrentCourse.ClassHour = app.Hours.Value;
%             app.CurrentCourse.Semester = app.Semester.Value;
%             app.CurrentCourse.Language = "中文";
        end

        % Cell edit callback: Obj2Way
        function Obj2WayCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.O2W{indices(1),indices(2)} = newData;
        end

        % Button pushed function: Save
        function SavePushed(app, event)
            results = calcGA(app);
            dat = app.ListObj.Data;
            dat(:,end) = num2cell(results)';
            app.ListObj.Data = dat;
            % 填写课程目标达成度分析文本模板
            text = TextMaker(app.CurrentCourse,app.Transcript,results);
            app.ResultText.Value = text;
            % UI控件数据
            obj2Way.Data = app.Obj2Way.Data;
            obj2Way.ColumnName = app.Obj2Way.ColumnName;
            obj2Way.ColumnFormat = app.Obj2Way.ColumnFormat;
            obj2Way.ColumnWidth = app.Obj2Way.ColumnWidth;
            listObj.Data = app.ListObj.Data;
            % UI内部变量
            cc = app.CurrentCourse;
            tr = app.Transcript;
            pathFile = strcat(app.DefaultSavePath,'app1.mat');
            % 保存数据
            save(pathFile,'obj2Way','listObj','cc','tr','text')
            app.Info.Value = sprintf('程序数据存盘：%s',pathFile);
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            pathFile = strcat(app.DefaultSavePath,'app1.mat');
            app.Info.Value = sprintf('从%s中载入数据',pathFile);
            load(pathFile,'obj2Way','listObj','cc','tr','text');
            % 载入属性
            app.Transcript = tr;
            app.CurrentCourse = cc;
            % 载入控件状态
            app.ResultText.Value = text;
            app.ListObj.Data = listObj.Data;
            app.Obj2Way.Enable = 'on';
            app.Obj2Way.ColumnName = obj2Way.ColumnName;
            app.Obj2Way.ColumnFormat = obj2Way.ColumnFormat;
            app.Obj2Way.ColumnWidth = obj2Way.ColumnWidth;
            app.Obj2Way.Data = obj2Way.Data;
            app.Name.Value = cc.Title;
            updateCourseInfo(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = '能源化学工程专业课程质量评价';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '课程名称';
            app.Name = uidropdown(app.UIFigure);
            app.Name.ValueChangedFcn = createCallbackFcn(app, @NameValueChanged, true);
            app.Import = uibutton(app.UIFigure, 'push');
            app.Import.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.Import.Text = '导入成绩单';
            app.ListObj = uitable(app.UIFigure);
            app.ListObj.ColumnName = {'编号'; '课程目标'; '达成度'};
            app.ListObj.RowName = {};
            app.Obj2Way = uitable(app.UIFigure);
            app.Obj2Way.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Obj2Way.RowName = {};
            app.Obj2Way.CellEditCallback = createCallbackFcn(app, @Obj2WayCellEdit, true);
            app.ResultText = uitextarea(app.UIFigure);
            app.Info = uitextarea(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '课程代码';
            app.Code = uieditfield(app.UIFigure, 'text');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '课程类型';
            app.Category = uieditfield(app.UIFigure, 'text');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '课程性质';
            app.CompulsoryOrNot = uieditfield(app.UIFigure, 'text');
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '课程学分';
            app.Credit = uieditfield(app.UIFigure, 'text');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '课程学时';
            app.Hours = uieditfield(app.UIFigure, 'text');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '开课学期';
            app.Semester = uieditfield(app.UIFigure, 'text');
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SavePushed, true);
            app.Save.Text = '计算达成度';
            app.Load = uibutton(app.UIFigure, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = '载入存盘数据';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Goal_UI_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end